classdef AWFOM_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SolverStatusTextArea           matlab.ui.control.TextArea
        SolverStatusTextAreaLabel      matlab.ui.control.Label
        ViewResultsButton              matlab.ui.control.Button
        SelectsolversforcomparisonLabel  matlab.ui.control.Label
        SurrogateOptimisationCheckBox  matlab.ui.control.CheckBox
        GlobalSearchCheckBox           matlab.ui.control.CheckBox
        SimulatedAnnealingCheckBox     matlab.ui.control.CheckBox
        ParticleSwarmCheckBox          matlab.ui.control.CheckBox
        patternsearchCheckBox          matlab.ui.control.CheckBox
        fminconCheckBox                matlab.ui.control.CheckBox
        SolverOptionsButton            matlab.ui.control.Button
        WindFarmParameterCreationButton  matlab.ui.control.Button
        ExportResultsButton            matlab.ui.control.Button
        RunOptimisationforcurrentfarmButton  matlab.ui.control.Button
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear 
            clc
            addpath(genpath('../AWFOM'));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: RunOptimisationforcurrentfarmButton
        function RunOptimisationforcurrentfarmButtonPushed(app, event)
            selected_solvers = [app.fminconCheckBox.Value, ...
                                app.patternsearchCheckBox.Value, ...
                                app.ParticleSwarmCheckBox.Value, ...
                                app.GlobalSearchCheckBox.Value, ...
                                app.SimulatedAnnealingCheckBox.Value, ...
                                app.SurrogateOptimisationCheckBox.Value];

            if sum(selected_solvers) == 0

                errordlg('Please select a solver using the checkboxes',...
                         'Insufficient Solver Selection')
                return    
            end
            
        
            farm_optimiser(selected_solvers, app.SolverStatusTextArea);
        end

        % Button pushed function: WindFarmParameterCreationButton
        function WindFarmParameterCreationButtonPushed(app, event)
            waitfor(AWFOM_farmgen)
        end

        % Button pushed function: SolverOptionsButton
        function SolverOptionsButtonPushed(app, event)
            waitfor(AWFOM_solveropts(app.fminconCheckBox.Value, ...
                                     app.patternsearchCheckBox.Value, ...                                     
                                     app.ParticleSwarmCheckBox.Value, ...
                                     app.GlobalSearchCheckBox.Value, ...
                                     app.SimulatedAnnealingCheckBox.Value, ...                                     
                                     app.SurrogateOptimisationCheckBox.Value))
        end

        % Button pushed function: ViewResultsButton
        function ViewResultsButtonPushed(app, event)
            waitfor(AWFOM_results)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RunOptimisationforcurrentfarmButton = uibutton(app.UIFigure, 'push');
            app.RunOptimisationforcurrentfarmButton.ButtonPushedFcn = createCallbackFcn(app, @RunOptimisationforcurrentfarmButtonPushed, true);
            app.RunOptimisationforcurrentfarmButton.Text = 'Run Optimisation for current farm';
            app.ExportResultsButton = uibutton(app.UIFigure, 'push');
            app.ExportResultsButton.Text = 'Export Results';
            app.WindFarmParameterCreationButton = uibutton(app.UIFigure, 'push');
            app.WindFarmParameterCreationButton.ButtonPushedFcn = createCallbackFcn(app, @WindFarmParameterCreationButtonPushed, true);
            app.WindFarmParameterCreationButton.Text = 'Wind Farm Parameter Creation';
            app.SolverOptionsButton = uibutton(app.UIFigure, 'push');
            app.SolverOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @SolverOptionsButtonPushed, true);
            app.SolverOptionsButton.Text = 'Solver Options';
            app.fminconCheckBox = uicheckbox(app.UIFigure);
            app.fminconCheckBox.Text = 'fmincon';
            app.fminconCheckBox.Value = true;
            app.patternsearchCheckBox = uicheckbox(app.UIFigure);
            app.patternsearchCheckBox.Text = 'patternsearch';
            app.ParticleSwarmCheckBox = uicheckbox(app.UIFigure);
            app.ParticleSwarmCheckBox.Text = 'Particle Swarm';
            app.SimulatedAnnealingCheckBox = uicheckbox(app.UIFigure);
            app.SimulatedAnnealingCheckBox.Enable = 'off';
            app.SimulatedAnnealingCheckBox.Text = 'Simulated Annealing';
            app.GlobalSearchCheckBox = uicheckbox(app.UIFigure);
            app.GlobalSearchCheckBox.Enable = 'off';
            app.GlobalSearchCheckBox.Text = 'Global Search';
            app.SurrogateOptimisationCheckBox = uicheckbox(app.UIFigure);
            app.SurrogateOptimisationCheckBox.Enable = 'off';
            app.SurrogateOptimisationCheckBox.Text = 'Surrogate Optimisation';
            app.SelectsolversforcomparisonLabel = uilabel(app.UIFigure);
            app.SelectsolversforcomparisonLabel.Text = 'Select solvers for comparison';
            app.ViewResultsButton = uibutton(app.UIFigure, 'push');
            app.ViewResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewResultsButtonPushed, true);
            app.ViewResultsButton.Text = 'View Results';
            app.SolverStatusTextAreaLabel = uilabel(app.UIFigure);
            app.SolverStatusTextAreaLabel.Text = 'Solver Status';
            app.SolverStatusTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AWFOM_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end